classdef proses < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        KembalikeBerandaButton  matlab.ui.control.Button
        AreaEditField_7         matlab.ui.control.EditField
        AreaEditFieldLabel_7    matlab.ui.control.Label
        AreaEditField_6         matlab.ui.control.EditField
        AreaEditFieldLabel_6    matlab.ui.control.Label
        IdentifikasiButton      matlab.ui.control.Button
        AreaEditField_5         matlab.ui.control.EditField
        AreaEditFieldLabel_5    matlab.ui.control.Label
        AreaEditField_4         matlab.ui.control.EditField
        AreaEditFieldLabel_4    matlab.ui.control.Label
        AreaEditField_3         matlab.ui.control.EditField
        AreaEditFieldLabel_3    matlab.ui.control.Label
        AreaEditField_2         matlab.ui.control.EditField
        AreaEditFieldLabel_2    matlab.ui.control.Label
        AreaEditField           matlab.ui.control.EditField
        AreaEditFieldLabel      matlab.ui.control.Label
        UITable                 matlab.ui.control.Table
        ResetButton             matlab.ui.control.Button
        ProcessButton           matlab.ui.control.Button
        StopButton              matlab.ui.control.Button
        StartButton             matlab.ui.control.Button
        BrowseImageButton       matlab.ui.control.Button
        Image                   matlab.ui.control.Image
        Asli                    matlab.ui.control.UIAxes
        Binner                  matlab.ui.control.UIAxes
        Blue                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function sublot(app, event)
            my_table = readtable('data_latih.xlsx','Range','A2:E121');
            
            app.UITable.Data = my_table;
        end

        % Button pushed function: BrowseImageButton
        function BrowseImageButtonPushed(app, event)
        global Im
        [nama_file,nama_path] = uigetfile({'*.*'});
            if ~isequal(nama_file,0)
                Im = imread(fullfile(nama_path,nama_file));
                imshow(Im, 'Parent' ,app.Asli);
            else
            end
            handle.IMG = Im;
            guidata(app.BrowseImageButton,Im);
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            
        end

        % Button down function: Asli
        function AsliButtonDown(app, event)
            
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            global Im
            
            Im2 = imresize(Im,[300,300]);
            Blue = Im2(:,:,3);
            [A, R] = size(Blue);
            Seg = zeros(A,R);
            
            for k = 1 : A
                for l = 1 : R
                    if Blue(k,l) > 60
                    Seg(k,l)=0;
                    else
                    Seg(k,l)=1;
                    end
                end
            end
            
            Seg2 = imfill(Seg);
            AA=bwlabeln(Seg2,8);
            a=max(max(AA));
            S=regionprops(Seg2,'Area');
            I_seg=ismember(AA, find([S.Area]>=100));
            
            for c=1:a
                area=0;
                perimeter=0;
                    for i=1:300
                        for j=1:300
                            if AA(i,j)==c
                            area=area+1;
                            end
                        end
                    end

                    for i=2:299
                        for j=2:299
                            if AA(i,j)==c  
                                if AA(i,j)~=AA(i-1,j) || AA(i,j)~=AA(i+1,j) || AA(i,j)~=AA(i,j-1)|| AA(i,j)~=AA(i,j+1)perimeter=perimeter+1;
                                end
                            end
                        end
                    end
                    luas(c)=area;
                    keliling(c) = perimeter;
             end
        
             hitung=0;
        
             for c=1:a
                if luas(c)>=100
                hitung = hitung+1;
                luas_area(hitung) = luas(c);
                keliling_area(hitung) = keliling(c);
                end
             end

             jum_luas=0;
             jum_keliling=0;

             for i=1:hitung
                 jum_luas = jum_luas + luas_area(i);
                 jum_keliling = jum_keliling + keliling_area(i);
             end

             phi = 3.14;
             area = jum_luas;
             perimeter = jum_keliling;
             metric = perimeter^2/area;
             circularity = 4*phi*area/perimeter^2;
             diameter = sqrt(area/phi)*2;
             
             imshow(Blue, 'Parent',app.Blue);
             imshow(Seg2, 'Parent',app.Binner);
             
             extract = [area]
             
             app.AreaEditField.Value = extract;
             %app.AreaEditField_2.Value = (Extract,1);
             %app.AreaEditField_3.Value = (Extract,2);
             %app.AreaEditField_4.Value = (Extract,3);
             %app.AreaEditField_5.Value = (Extract,4);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'Sistem identifikasi pegagan2.png';
            app.Blue = uiaxes(app.UIFigure);
            title(app.Blue, 'Blue')
            xlabel(app.Blue, 'X')
            ylabel(app.Blue, 'Y')
            zlabel(app.Blue, 'Z')
            app.Binner = uiaxes(app.UIFigure);
            title(app.Binner, 'Biner')
            xlabel(app.Binner, 'X')
            ylabel(app.Binner, 'Y')
            zlabel(app.Binner, 'Z')
            app.Asli = uiaxes(app.UIFigure);
            title(app.Asli, 'Asli')
            xlabel(app.Asli, 'X')
            ylabel(app.Asli, 'Y')
            zlabel(app.Asli, 'Z')
            app.Asli.ButtonDownFcn = createCallbackFcn(app, @AsliButtonDown, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'prosesbg.png';
            app.BrowseImageButton = uibutton(app.UIFigure, 'push');
            app.BrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageButtonPushed, true);
            app.BrowseImageButton.Text = 'Browse Image';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.Text = 'Stop';
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.Text = 'Reset';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Area'; 'Perimeter'; 'Bentuk'; 'Circularity'};
            app.UITable.RowName = {};
            app.AreaEditFieldLabel = uilabel(app.UIFigure);
            app.AreaEditFieldLabel.Text = 'Area';
            app.AreaEditField = uieditfield(app.UIFigure, 'text');
            app.AreaEditField.Value = 'sss';
            app.AreaEditFieldLabel_2 = uilabel(app.UIFigure);
            app.AreaEditFieldLabel_2.Text = 'Parameter';
            app.AreaEditField_2 = uieditfield(app.UIFigure, 'text');
            app.AreaEditField_2.Value = 'sss';
            app.AreaEditFieldLabel_3 = uilabel(app.UIFigure);
            app.AreaEditFieldLabel_3.Text = 'Metrik';
            app.AreaEditField_3 = uieditfield(app.UIFigure, 'text');
            app.AreaEditField_3.Value = 'sss';
            app.AreaEditFieldLabel_4 = uilabel(app.UIFigure);
            app.AreaEditFieldLabel_4.Text = 'Diameter';
            app.AreaEditField_4 = uieditfield(app.UIFigure, 'text');
            app.AreaEditField_4.Value = 'sss';
            app.AreaEditFieldLabel_5 = uilabel(app.UIFigure);
            app.AreaEditFieldLabel_5.Text = 'Eccentricity';
            app.AreaEditField_5 = uieditfield(app.UIFigure, 'text');
            app.AreaEditField_5.Value = 'sss';
            app.IdentifikasiButton = uibutton(app.UIFigure, 'push');
            app.IdentifikasiButton.Text = 'Identifikasi';
            app.AreaEditFieldLabel_6 = uilabel(app.UIFigure);
            app.AreaEditFieldLabel_6.Text = 'Nilai K';
            app.AreaEditField_6 = uieditfield(app.UIFigure, 'text');
            app.AreaEditField_6.Value = 'sss';
            app.AreaEditFieldLabel_7 = uilabel(app.UIFigure);
            app.AreaEditFieldLabel_7.Text = 'Hasil';
            app.AreaEditField_7 = uieditfield(app.UIFigure, 'text');
            app.AreaEditField_7.Value = 'sss';
            app.KembalikeBerandaButton = uibutton(app.UIFigure, 'push');
            app.KembalikeBerandaButton.Text = 'Kembali ke Beranda';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proses

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end